classdef ProbProj1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProbabilityofeachcharacterButton  matlab.ui.control.Button
        type                       matlab.ui.control.Button
        probabilitydistributionTypeButton  matlab.ui.control.Button
        kurt                       matlab.ui.control.Button
        Ske                        matlab.ui.control.Button
        Var                        matlab.ui.control.Button
        MeanButton_2               matlab.ui.control.Button
        KurtosisButton             matlab.ui.control.Button
        SkewnessButton             matlab.ui.control.Button
        VarianceButton             matlab.ui.control.Button
        Mean                       matlab.ui.control.Button
        MostRepeted5LettersButton  matlab.ui.control.Button
        CDFButton                  matlab.ui.control.Button
        PleaseEnterFileNametoopenEditField  matlab.ui.control.EditField
        PleaseEnterFileNametoopenEditFieldLabel  matlab.ui.control.Label
        STATISTICALANALYSISOFTEXTFILESLabel  matlab.ui.control.Label
        PMFButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        getFILENAME=''; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Fileee)
            
        end

        % Button pushed function: PMFButton
        function PMFButtonPushed(app, event)
%             handels=app.get
%             Passfilename=get(Getfilename);
             OpenFile(app.getFILENAME,1);

        end

        % Callback function: PleaseEnterFileNametoopenEditField, 
        % PleaseEnterFileNametoopenEditField
        function PleaseEnterFileNametoopenEditFieldValueChanged(app, event)
            
            Getfilename = app.PleaseEnterFileNametoopenEditField.Value;
           % Getfilename ="D:\Probability\file.txt";
            app.getFILENAME=Getfilename;
        end

        % Button pushed function: CDFButton
        function CDFButtonPushed(app, event)
            OpenFile(app.getFILENAME,2);
        end

        % Button pushed function: MostRepeted5LettersButton
        function MostRepeted5LettersButtonPushed(app, event)
            OpenFile(app.getFILENAME,3);
        end

        % Button pushed function: Mean
        function MeanPushed(app, event)
            
            
        end

        % Button pushed function: VarianceButton
        function VarianceButtonPushed(app, event)
            [M,V,S,K, t]=Mean_Var(app.getFILENAME);
            text=num2str(V);
            app.Var.Text=text;
        end

        % Button pushed function: SkewnessButton
        function SkewnessButtonPushed(app, event)
           [M,V,S,K, t]=Mean_Var(app.getFILENAME);
            text=num2str(S);
            app.Ske.Text=text;
        end

        % Button pushed function: KurtosisButton
        function KurtosisButtonPushed(app, event)
            [M,V,S,K, t]=Mean_Var(app.getFILENAME);
            text=num2str(K);
            app.kurt.Text=text;
        end

        % Button pushed function: MeanButton_2
        function MeanButton_2Pushed(app, event)
            [M,V,S,K, t]=Mean_Var(app.getFILENAME);
            text=num2str(M);
            app.Mean.Text=text;
        end

        % Button pushed function: Ske
        function SkePushed(app, event)
             
        end

        % Button pushed function: kurt
        function kurtPushed(app, event)
            
        end

        % Button pushed function: Var
        function VarPushed(app, event)
            
        end

        % Button pushed function: probabilitydistributionTypeButton
        function probabilitydistributionTypeButtonPushed(app, event)
            [M,V,S,K, t]=Mean_Var(app.getFILENAME);
            text=t;
            app.type.Text=text;
        end

        % Button pushed function: ProbabilityofeachcharacterButton
        function ProbabilityofeachcharacterButtonPushed(app, event)
            OpenFile(app.getFILENAME,10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PMFButton = uibutton(app.UIFigure, 'push');
            app.PMFButton.ButtonPushedFcn = createCallbackFcn(app, @PMFButtonPushed, true);
            app.PMFButton.Text = 'PMF';
            app.STATISTICALANALYSISOFTEXTFILESLabel = uilabel(app.UIFigure);
            app.STATISTICALANALYSISOFTEXTFILESLabel.Text = 'STATISTICAL ANALYSIS OF TEXT FILES';
            app.PleaseEnterFileNametoopenEditFieldLabel = uilabel(app.UIFigure);
            app.PleaseEnterFileNametoopenEditFieldLabel.Text = 'Please, Enter File Name to open:';
            app.PleaseEnterFileNametoopenEditField = uieditfield(app.UIFigure, 'text');
            app.PleaseEnterFileNametoopenEditField.ValueChangedFcn = createCallbackFcn(app, @PleaseEnterFileNametoopenEditFieldValueChanged, true);
            app.PleaseEnterFileNametoopenEditField.ValueChangingFcn = createCallbackFcn(app, @PleaseEnterFileNametoopenEditFieldValueChanged, true);
            app.PleaseEnterFileNametoopenEditField.Tag = 'Get';
            app.CDFButton = uibutton(app.UIFigure, 'push');
            app.CDFButton.ButtonPushedFcn = createCallbackFcn(app, @CDFButtonPushed, true);
            app.CDFButton.Text = 'CDF';
            app.MostRepeted5LettersButton = uibutton(app.UIFigure, 'push');
            app.MostRepeted5LettersButton.ButtonPushedFcn = createCallbackFcn(app, @MostRepeted5LettersButtonPushed, true);
            app.MostRepeted5LettersButton.Text = 'Most Repeted 5 Letters';
            app.Mean = uibutton(app.UIFigure, 'push');
            app.Mean.ButtonPushedFcn = createCallbackFcn(app, @MeanPushed, true);
            app.Mean.Text = '';
            app.VarianceButton = uibutton(app.UIFigure, 'push');
            app.VarianceButton.ButtonPushedFcn = createCallbackFcn(app, @VarianceButtonPushed, true);
            app.VarianceButton.Text = 'Variance';
            app.SkewnessButton = uibutton(app.UIFigure, 'push');
            app.SkewnessButton.ButtonPushedFcn = createCallbackFcn(app, @SkewnessButtonPushed, true);
            app.SkewnessButton.Text = 'Skewness';
            app.KurtosisButton = uibutton(app.UIFigure, 'push');
            app.KurtosisButton.ButtonPushedFcn = createCallbackFcn(app, @KurtosisButtonPushed, true);
            app.KurtosisButton.Text = 'Kurtosis';
            app.MeanButton_2 = uibutton(app.UIFigure, 'push');
            app.MeanButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeanButton_2Pushed, true);
            app.MeanButton_2.Text = 'Mean';
            app.Var = uibutton(app.UIFigure, 'push');
            app.Var.ButtonPushedFcn = createCallbackFcn(app, @VarPushed, true);
            app.Var.Text = '';
            app.Ske = uibutton(app.UIFigure, 'push');
            app.Ske.ButtonPushedFcn = createCallbackFcn(app, @SkePushed, true);
            app.Ske.Text = '';
            app.kurt = uibutton(app.UIFigure, 'push');
            app.kurt.ButtonPushedFcn = createCallbackFcn(app, @kurtPushed, true);
            app.kurt.Text = '';
            app.probabilitydistributionTypeButton = uibutton(app.UIFigure, 'push');
            app.probabilitydistributionTypeButton.ButtonPushedFcn = createCallbackFcn(app, @probabilitydistributionTypeButtonPushed, true);
            app.probabilitydistributionTypeButton.Text = 'probability distribution Type';
            app.type = uibutton(app.UIFigure, 'push');
            app.type.Text = '';
            app.ProbabilityofeachcharacterButton = uibutton(app.UIFigure, 'push');
            app.ProbabilityofeachcharacterButton.ButtonPushedFcn = createCallbackFcn(app, @ProbabilityofeachcharacterButtonPushed, true);
            app.ProbabilityofeachcharacterButton.Text = 'Probability of each character';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProbProj1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end